classdef updated_app < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout          matlab.ui.container.GridLayout
        image3              matlab.ui.control.Image
        RestartButton       matlab.ui.control.Button
        playagain           matlab.ui.control.Label
        Image               matlab.ui.control.Image
        StarttheGameButton  matlab.ui.control.Button
        directions          matlab.ui.control.TextArea
        displaycorrectword  matlab.ui.control.Label
        ImpressiveLabel     matlab.ui.control.Label
        restartButton       matlab.ui.control.Button
        YouloseLabel        matlab.ui.control.Label
        row_5_column_4      matlab.ui.control.Label
        row_4_column_4      matlab.ui.control.Label
        row_3_column_4      matlab.ui.control.Label
        row_2_column_4      matlab.ui.control.Label
        row_1_column_4      matlab.ui.control.Label
        Image2              matlab.ui.control.Image
        row_5_column_1      matlab.ui.control.Label
        row_4_column_1      matlab.ui.control.Label
        row_3_column_1      matlab.ui.control.Label
        row_2_column_1      matlab.ui.control.Label
        row_1_column_1      matlab.ui.control.Label
        edit_field          matlab.ui.control.EditField
        submitButton        matlab.ui.control.Button
        row_5_column_5      matlab.ui.control.Label
        row_4_column_5      matlab.ui.control.Label
        row_3_column_5      matlab.ui.control.Label
        row_2_column_5      matlab.ui.control.Label
        row_1_column_5      matlab.ui.control.Label
        row_5_column_3      matlab.ui.control.Label
        row_4_column_3      matlab.ui.control.Label
        row_3_column_3      matlab.ui.control.Label
        row_2_column_3      matlab.ui.control.Label
        row_1_column_3      matlab.ui.control.Label
        row_5_column_2      matlab.ui.control.Label
        row_4_column_2      matlab.ui.control.Label
        row_3_column_2      matlab.ui.control.Label
        row_2_column_2      matlab.ui.control.Label
        row_1_column_2      matlab.ui.control.Label
        guess_the_word      matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: submitButton
        function submitButtonPushed(comp, event)
    %%% this function runs whenever the user presses the submit button%%%
    persistent pressCount  %% the persistent function retains memory of the amount of time the button was previously pressed
    if isempty(pressCount) %% if the button was not pressed, the press count is 0
        pressCount = 0;
    end
    
    pressCount = pressCount + 1; %% if the button was pressed, add one to the pressCount
    
    valueMatrix = upper(comp.edit_field.Value(1, :)); % creates matrix/vector that stores the 'guess' that user types in
    
    
    row = pressCount; %% creates variable, row, that is representative with the amount of times the submit button has been pressed 
    
    if row <= 5  % make sure the row number is not greater than 5 (user only has 5 attempts to guess the word)
        for col = 1:5  % creates variable col to represent the columns, and loops through columns 1 to 5 (accounting for amount of letters there are in the word)
            comp.(['row_' num2str(row) '_column_' num2str(col)]).Text = valueMatrix(col); %% sets the letter the user guessed to be input into a specific box in the grid configuration 
        end
    end


WORDS_array = []; %% creates empty array to input the potential correct words into 
    fid = fopen('WORDS.txt', 'r'); % Opens file with list of 3102 words
    while ~feof(fid) % while the file is opened 
        line = fgetl(fid); % read each word in the list 
        WORDS_array = [WORDS_array; line]; % add each word in the list to a 3102 x 1 vector 
    end
    
   
    global rand_num; %% makes ran_num a global variable that can be used outside the function
    if row == 1 %% if its the first time button is pressed
        rand_num = randi(3102); % pick a random number between 1-3102
    end

    correct_word = WORDS_array(rand_num, :); %% make the correct word the word that is in the position that the random number picked (always will be 1-3102:1)

    green_color = [0.55 0.85 0.55]; %% creates a green color
    yellow_color = [0.95 0.95 0.35]; %% creates a yellow color
    grey_color = [0.7 0.7 0.7]; %% creates a grey color
    green_letters = [0 0 0 0 0]; %% initializes a zeros vector
    yellow_letters = [0 0 0 0 0]; %% initalizes a zeros vector

    for j = 1:5 %% goes through each letter of the word
        if upper(correct_word(j)) == upper(comp.edit_field.Value(j)) %% if the correct letter is equal to the guessed letter
            green_letters(j) = 1; %% changes value of green_letters vector to 1
        end
    end

    for j = 1:5 %% goes through each letter of the word
        if green_letters(j) == 1 %% if the value in the green_letters vector is equal to 1
            continue; %% continue beyond this for-loop
        end

        if ismember(upper(comp.edit_field.Value(j)), upper(correct_word(green_letters == 0))) %% if the letter from the users guess is in the correct word but not in the correct place
            yellow_letters(j) = 1; %% change the value of the yellow_letters vector to 1
        end 
    end 
    
    for j = 1:5 %% goes through each letter of the word
        if green_letters(j) == 1  %% if the value of the green_letters vector is equal to 1 
            set(comp.(['row_' num2str(row) '_column_' num2str(j)]),'BackgroundColor', green_color); %% set the specific box in the grid configuration to a backrgound color of green 
            continue; %% continue beyond this loop
        end

        if yellow_letters(j) == 1 %if the value of the yellow_letters vector is equal to 1 
            set(comp.(['row_' num2str(row) '_column_' num2str(j)]), 'BackgroundColor', yellow_color); %% set the specific box in the grid configuration to a backrgound color of yellow
            continue; %% continue beyond this loop
        end

        set(comp.(['row_' num2str(row) '_column_' num2str(j)]), 'BackgroundColor', grey_color); %% if it is not yellow or green set the specific box in the grid configuration to a backrgound color of grey 
    end
    
    comp.edit_field.Value = ''; %% resets the edit field value to be empty

    pause(1.0); %% pauses for one second

    if green_letters == 1 %% if the user corrected guessed the word
        comp.ImpressiveLabel.Visible = 'on'; %% display a label that says 'impressive'
        pressCount = 0; %% reset the amount of time the user has pressed the button to zero
        pause(1.5); %% pause for 1.5 second
        comp.ImpressiveLabel.Visible = 'off'; %% turns off impressive label 
        comp.playagain.Visible = 'on'; %% turn on the play again label 
        comp.RestartButton.Visible = 'on';%% turn on the restart button label 
        comp.image3.Visible = 'on'; %% turns on our cracked logo 
    elseif row == 5 %% if the user didn't guess the word
        comp.displaycorrectword.Text = correct_word; %% makes the text of the label the correct word
        comp.displaycorrectword.Visible = 'on'; %% displays the correct word label
        pause(1.5); %% pauses for 1.5 seconds
        comp.displaycorrectword.Visible = 'off'; %% turns the correct word label off
        comp.YouloseLabel.Visible = 'on'; %% display label that says 'you lose'
        comp.restartButton.Visible = 'on'; %% dispplay label that allows user to restart
        pressCount = 0; %% reset the amount of time the user has pressed the button to zero
    end
        
        end

        % Button pushed function: restartButton
        function restartButtonPushed(comp, event)

         %% if restart button is pressed 
        comp.YouloseLabel.Visible = 'off'; %% turn off the 'you lose' label
        comp.restartButton.Visible = 'off'; %% turn off the restart button label 
        white_color = [1 1 1]; %% variable to represent white color 
        blank_grid = ''; %% create a blank grid variable that has no words in the grid
        for i = 1:5 % go through rows
            for j = 1:5 % go through columns
                set(comp.(['row_' num2str(i) '_column_' num2str(j)]),'BackgroundColor', white_color); %% sets background color of each row/column to white
                set(comp.(['row_' num2str(i) '_column_' num2str(j)]),'Text', blank_grid); %% sets the text within each row/column to blank 
            end
        end
       
        end

        % Button pushed function: StarttheGameButton
        function StarttheGameButtonPushed(comp, event)
            %% if start the game button is pushed 
            comp.directions.Visible = 'off'; %% turns off the label that displays the directions of the game
            comp.StarttheGameButton.Visible = 'off'; %% turns off the label that displays the start game button
            comp.Image.Visible = 'off'; %% turns off ouf logo image
            comp.directions.Editable = 'off'; %% turns off the editability of the directions 

        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(comp, event)
        comp.image3.Visible = 'off'; %% turn off the logo image 
        comp.playagain.Visible = 'off'; %% turn off the play again label 
        comp.RestartButton.Visible = 'off'; %% turn off the play again label 
        white_color = [1 1 1]; %% variable to represent white color 
        blank_grid = ''; %% create a blank grid variable that has no words in the grid
        for i = 1:5 % go through rows
            for j = 1:5 % go through columns
                set(comp.(['row_' num2str(i) '_column_' num2str(j)]),'BackgroundColor', white_color); %% sets background color of each row/column to white
                set(comp.(['row_' num2str(i) '_column_' num2str(j)]),'Text', blank_grid); %% sets the text within each row/column to blank 
            end
        end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 624 475];
            comp.GridLayout = uigridlayout(comp);
            comp.guess_the_word = uilabel(comp.GridLayout);
            comp.guess_the_word.Text = {'Guess the word and check your answer by pressing the submit button.'; 'lowercase letters only!'};
            comp.row_1_column_2 = uilabel(comp.GridLayout);
            comp.row_1_column_2.Text = '';
            comp.row_2_column_2 = uilabel(comp.GridLayout);
            comp.row_2_column_2.Text = '';
            comp.row_3_column_2 = uilabel(comp.GridLayout);
            comp.row_3_column_2.Text = '';
            comp.row_4_column_2 = uilabel(comp.GridLayout);
            comp.row_4_column_2.Text = '';
            comp.row_5_column_2 = uilabel(comp.GridLayout);
            comp.row_5_column_2.Text = '';
            comp.row_1_column_3 = uilabel(comp.GridLayout);
            comp.row_1_column_3.Text = '';
            comp.row_2_column_3 = uilabel(comp.GridLayout);
            comp.row_2_column_3.Text = '';
            comp.row_3_column_3 = uilabel(comp.GridLayout);
            comp.row_3_column_3.Text = '';
            comp.row_4_column_3 = uilabel(comp.GridLayout);
            comp.row_4_column_3.Text = '';
            comp.row_5_column_3 = uilabel(comp.GridLayout);
            comp.row_5_column_3.Text = '';
            comp.row_1_column_5 = uilabel(comp.GridLayout);
            comp.row_1_column_5.Text = '';
            comp.row_2_column_5 = uilabel(comp.GridLayout);
            comp.row_2_column_5.Text = '';
            comp.row_3_column_5 = uilabel(comp.GridLayout);
            comp.row_3_column_5.Text = '';
            comp.row_4_column_5 = uilabel(comp.GridLayout);
            comp.row_4_column_5.Text = '';
            comp.row_5_column_5 = uilabel(comp.GridLayout);
            comp.row_5_column_5.Text = '';
            comp.submitButton = uibutton(comp.GridLayout, 'push');
            comp.submitButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @submitButtonPushed, true);
            comp.submitButton.Text = 'submit';
            comp.edit_field = uieditfield(comp.GridLayout, 'text');
            comp.edit_field.CharacterLimits = [0 5];
            comp.edit_field.InputType = 'letters';
            comp.row_1_column_1 = uilabel(comp.GridLayout);
            comp.row_1_column_1.Text = '';
            comp.row_2_column_1 = uilabel(comp.GridLayout);
            comp.row_2_column_1.Text = '';
            comp.row_3_column_1 = uilabel(comp.GridLayout);
            comp.row_3_column_1.Text = '';
            comp.row_4_column_1 = uilabel(comp.GridLayout);
            comp.row_4_column_1.Text = '';
            comp.row_5_column_1 = uilabel(comp.GridLayout);
            comp.row_5_column_1.Text = '';
            comp.Image2 = uiimage(comp.GridLayout);
            comp.Image2.ImageSource = fullfile(pathToMLAPP, 'cracked_egg.png');
            comp.row_1_column_4 = uilabel(comp.GridLayout);
            comp.row_1_column_4.Text = '';
            comp.row_2_column_4 = uilabel(comp.GridLayout);
            comp.row_2_column_4.Text = '';
            comp.row_3_column_4 = uilabel(comp.GridLayout);
            comp.row_3_column_4.Text = '';
            comp.row_4_column_4 = uilabel(comp.GridLayout);
            comp.row_4_column_4.Text = '';
            comp.row_5_column_4 = uilabel(comp.GridLayout);
            comp.row_5_column_4.Text = '';
            comp.YouloseLabel = uilabel(comp.GridLayout);
            comp.YouloseLabel.Visible = 'off';
            comp.YouloseLabel.Text = '   You lose.';
            comp.restartButton = uibutton(comp.GridLayout, 'push');
            comp.restartButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @restartButtonPushed, true);
            comp.restartButton.Visible = 'off';
            comp.restartButton.Text = 'restart';
            comp.ImpressiveLabel = uilabel(comp.GridLayout);
            comp.ImpressiveLabel.Visible = 'off';
            comp.ImpressiveLabel.Text = 'Impressive.';
            comp.displaycorrectword = uilabel(comp.GridLayout);
            comp.displaycorrectword.Visible = 'off';
            comp.displaycorrectword.Text = '';
            comp.directions = uitextarea(comp.GridLayout);
            comp.directions.Value = {'  How to Play.'; ''; '  Guess the Wordle in 5 tries.'; ''; '    If the letter is in the correct position and in the correct word, the background of the letter will turn GREEN.'; ''; '    If the letter is in the correct word but not in the correct position, the background of the letter will turn YELLOW.'; ''; '    If the letter is not in the correct word, the background of the letter will turn GREY.'};
            comp.StarttheGameButton = uibutton(comp.GridLayout, 'push');
            comp.StarttheGameButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @StarttheGameButtonPushed, true);
            comp.StarttheGameButton.Text = 'Start the Game!';
            comp.Image = uiimage(comp.GridLayout);
            comp.Image.ImageSource = fullfile(pathToMLAPP, 'cracked_egg.png');
            comp.playagain = uilabel(comp.GridLayout);
            comp.playagain.Visible = 'off';
            comp.playagain.Text = 'Play again and guess a new word!';
            comp.RestartButton = uibutton(comp.GridLayout, 'push');
            comp.RestartButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RestartButtonPushed, true);
            comp.RestartButton.Visible = 'off';
            comp.RestartButton.Text = 'Restart';
            comp.image3 = uiimage(comp.GridLayout);
            comp.image3.Visible = 'off';
            comp.image3.ImageSource = fullfile(pathToMLAPP, 'cracked_egg.png');
        end
    end
end